classdef app_robot < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure     matlab.ui.Figure
        RUNAIButton  matlab.ui.control.StateButton
        UIAxes_3     matlab.ui.control.UIAxes
        UIAxes_2     matlab.ui.control.UIAxes
        UIAxes       matlab.ui.control.UIAxes
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Value changed function: RUNAIButton
        function RUNAIButtonValueChanged(app, event)
            c = webcam('Fantech Luminous C30' );
            % c = webcam('EƖgato Virtual Camera');
            load 'detectorYOLOv2VaporLiquid.mat';

            while true
                e = snapshot(c);
                [bboxes, scores,label] = detect(detectorYolov2,e);
                scores1 = scores;
                imshow(e,'Parent',app.UIAxes);
                title(app.UIAxes,'Web cam');
                if (~isempty(bboxes))
                    e = insertObjectAnnotation(e,'rectangle',bboxes,scores,'Color','green');
                    Tmax1 = max(scores1); % Define threshold here
                    idx1 = scores1 >= Tmax1;
                    bbox10 = bboxes(idx1,:); % This logic doesn't change    Xc1 = bboxes(:,1);%kiri
                    Xc1 = bboxes(:,1);%bawah
                    Yc1 = bboxes(:,2);%bawah
                    a1 = bboxes(:,3);%kiri
                    b1 = bboxes(:,4);%bawah
                    cenx1 = ((Xc1+(a1/2))); %511 %-----Centroids and Xt Yt
                    ceny1 = ((Yc1+(b1/2))); %391
                    Xt1 = cenx1(:,1);
                    Yt1 = ceny1(:,1);
                end
                imshow(e, 'Parent', app.UIAxes_2);
                title(app.UIAxes_2,'Detector');
                drawnow;
            end
           
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Title')
            app.UIAxes.XTick = [];
            app.UIAxes.YTick = [];
            app.UIAxes_2 = uiaxes(app.UIFigure);
            title(app.UIAxes_2, 'Title')
            app.UIAxes_2.XTick = [];
            app.UIAxes_2.YTick = [];
            app.UIAxes_3 = uiaxes(app.UIFigure);
            title(app.UIAxes_3, 'Title')
            app.UIAxes_3.XTick = [];
            app.UIAxes_3.YTick = [];
            app.RUNAIButton = uibutton(app.UIFigure, 'state');
            app.RUNAIButton.ValueChangedFcn = createCallbackFcn(app, @RUNAIButtonValueChanged, true);
            app.RUNAIButton.Text = 'RUN AI';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app_robot

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end